classdef blackjack < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        backgroundImg     matlab.ui.control.Image
        hitButton         matlab.ui.control.Button
        standButton       matlab.ui.control.Button
        dealButton        matlab.ui.control.Button
        betButton         matlab.ui.control.Button
        chipsImg          matlab.ui.control.Image
        playerCard1       matlab.ui.control.Image
        playerCard2       matlab.ui.control.Image
        playerCard3       matlab.ui.control.Image
        playerCard4       matlab.ui.control.Image
        playerCard5       matlab.ui.control.Image
        playerCard6       matlab.ui.control.Image
        playerCard7       matlab.ui.control.Image
        playerCard8       matlab.ui.control.Image
        playerCard9       matlab.ui.control.Image
        playerCard10      matlab.ui.control.Image
        playerCard11      matlab.ui.control.Image
        dealerCard1       matlab.ui.control.Image
        dealerCard2       matlab.ui.control.Image
        dealerCard3       matlab.ui.control.Image
        dealerCard4       matlab.ui.control.Image
        dealerCard5       matlab.ui.control.Image
        dealerCard6       matlab.ui.control.Image
        dealerCard7       matlab.ui.control.Image
        dealerCard8       matlab.ui.control.Image
        dealerCard9       matlab.ui.control.Image
        dealerCard10      matlab.ui.control.Image
        dealerCardHidden  matlab.ui.control.Image
        Label             matlab.ui.control.Label
        betText           matlab.ui.control.TextArea
        TOTALLabel        matlab.ui.control.Label
        moneyText         matlab.ui.control.TextArea
        EnternewbetamountEditFieldLabel  matlab.ui.control.Label
        changeBetField    matlab.ui.control.NumericEditField
        playerBlackjack   matlab.ui.control.Label
        win               matlab.ui.control.Label
        lose              matlab.ui.control.Label
        push              matlab.ui.control.Label
        valuesCheckBox    matlab.ui.control.CheckBox
        dealerValueText   matlab.ui.control.NumericEditField
        playerValueText   matlab.ui.control.NumericEditField
        gameOverMsg       matlab.ui.control.TextArea
        overBet           matlab.ui.control.Label
        statsCheckBox     matlab.ui.control.CheckBox
        statsGraph        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Deck = 1:52; % deck matrix cards used not replaced
        indexPlayer = 3; % index of what card to show for player
        indexDealer  = 3; % index of what car to show for dealer
        playerVal % player value 
        dealerVal % dealer value
        bet = 0 % bet amount
        money = 100 % total money
        a % first card value for player
        b % second card value for player
        c % every card value for player from a hit
        x % first card value for dealer
        y % second card value for dealer
        z % every card value for dealer from a hit
        wins = 0; % number of wins
        losses = 0; % number of losses
        pushes = 0; % number of pushes
        blackjacks = 0; % number of blackjacks
    end
    
    methods (Access = private)
        
        function [cardStr,deck,cardVal] = deal(app,deck)
            fid = fopen('cardFileNames.txt','r');
            if fid == -1
                error('Cannot open the file!');
            end
            if isempty(deck)
                deck = 1:52;
                audioFile = 'shuffleSound.mp3';
                clear w Fs
                [w, Fs,] = audioread(audioFile);
                sound (w, Fs);
            end
            cardIndex = randi([1 length(deck)]);
            for i = 1:deck(cardIndex)-1
                fgets(fid);
            end
            cardStr = fscanf(fid,'%s',1);
            deck(cardIndex) = [];
            cardVal = fscanf(fid,'%d',1);
            fclose(fid);
        end
        
        function [playerRes,dealerRes] = outcome(app,playerVal,dealerVal)
            narginchk(2, 3);
            if nargin == 2
                dealerVal = 0;
            end
            if playerVal == 21 %If player has a blackjack 
                playerRes = 1;
            elseif playerVal > 21 %If player has a bust
                playerRes = 2;
            elseif playerVal < 21 %If player can deal again
                playerRes = 3;
            else 
                error('something went wrong in the check function')
            end
            if dealerVal == 21 % If dealer has a blackjack 
                dealerRes = 1;
            elseif dealerVal > 21 %If dealer has a bust
                dealerRes = 2;
            elseif dealerVal < 21 %If dealer is lower than 21
                dealerRes = 3;
            else 
                error('something went wrong in the check function')
            end
        end
        
        function reset(app)
            if app.money <= 0 
                app.gameOverMsg.Visible = 'on';
                pause(2);
                closereq;
            else
                app.lose.Visible = 'off';
                app.win.Visible = 'off';
                app.push.Visible = 'off';
                app.playerBlackjack.Visible = 'off';
                app.standButton.Visible = 'off';
                app.hitButton.Visible = 'off';
                pause(2);
                if app.bet <= app.money 
                    app.dealButton.Visible = 'on';
                end
                app.dealButton.Visible = 'on';
                app.betButton.Visible = 'on';
                app.playerCard1.Visible = 'off';
                app.playerCard2.Visible = 'off';
                app.playerCard3.Visible = 'off';
                app.playerCard4.Visible = 'off';
                app.playerCard5.Visible = 'off';
                app.playerCard6.Visible = 'off';
                app.playerCard7.Visible = 'off';
                app.playerCard8.Visible = 'off';
                app.playerCard9.Visible = 'off';
                app.playerCard10.Visible = 'off';
                app.playerCard11.Visible = 'off';
                app.dealerCard1.Visible = 'off';
                app.dealerCard2.Visible = 'off';
                app.dealerCard3.Visible = 'off';
                app.dealerCard4.Visible = 'off';
                app.dealerCard5.Visible = 'off';
                app.dealerCard6.Visible = 'off';
                app.dealerCard7.Visible = 'off';
                app.dealerCard8.Visible = 'off';
                app.dealerCard9.Visible = 'off';
                app.dealerCard10.Visible = 'off';
                app.dealerCardHidden.Visible = 'off';
                app.dealerValueText.Value = 0;
                app.playerValueText.Value = 0;
                app.indexPlayer = 3;
                app.indexDealer = 3;
                app.playerVal = 0;
                app.dealerVal = 0;
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: betButton
        function changeBetPushed(app, event)
            app.betText.Visible = 'off';
            app.Label.Visible = 'off';
            app.EnternewbetamountEditFieldLabel.Visible = 'on';
            app.changeBetField.Visible = 'on';
        end

        % Value changed function: changeBetField
        function betChanged(app, event)
            value = app.changeBetField.Value;
            if value > app.money
                app.overBet.Visible = 'on';
                app.EnternewbetamountEditFieldLabel.Visible = 'off';
                app.changeBetField.Visible = 'off';
                app.betText.Visible = 'on';
                app.Label.Visible = 'on';
                pause(2);
                app.overBet.Visible = 'off';
                app.changeBetField.Value = app.bet;
            else
                app.overBet.Visible = 'off';
                app.EnternewbetamountEditFieldLabel.Visible = 'off';
                app.changeBetField.Visible = 'off';
                app.betText.Visible = 'on';
                app.Label.Visible = 'on';
                app.betText.Value = num2str(value);
                if value >= 1
                    app.chipsImg.Visible = 'on';
                    app.dealButton.Visible = 'on';
                elseif value < 1
                    app.chipsImg.Visible = 'off';
                end
                app.bet = value;
            end
        end

        % Button pushed function: dealButton
        function dealPushed(app, event)
            audioFile1 = 'deltCards.mp3';
            clear g Gs
            [g, Gs,] = audioread(audioFile1);
            sound (g, Gs);
            app.betButton.Visible = 'off';
            app.hitButton.Visible = 'on';
            app.standButton.Visible = 'on';
            app.dealButton.Visible = 'off';
            app.playerCard1.Visible = 'on';
            app.playerCard2.Visible = 'on';
            app.dealerCard1.Visible = 'on';
            app.dealerCardHidden.Visible = 'on';
            deck = app.Deck;
            [cardStr,deck,app.a] = deal(app,deck);
            % use deal function to return image sources
            app.playerCard1.ImageSource = cardStr;
            [cardStr,deck,app.b] = deal(app,deck);
            app.playerCard2.ImageSource = cardStr;
            app.playerVal = app.a + app.b;
            while (app.playerVal > 21) && (app.a == 11 || app.b == 11)
                if app.a == 11
                    app.playerVal = app.playerVal - 10;
                    app.a = 1;
                elseif app.b == 11
                    app.playerVal = app.playerVal - 10;
                    app.b = 1;
                end
            end
            app.playerValueText.Value = app.playerVal;
            [pres,dres] = outcome(app,app.playerVal);
            if pres == 1 % blackjack
                app.money = app.money + 2*app.bet;
                app.moneyText.Value = num2str(app.money);
                app.playerBlackjack.Visible = 'on';
                pause(2);
                app.blackjacks = app.blackjacks + 1;
                app.wins = app.wins + 1;
                reset(app);
            end
            [cardStr,deck,app.x] = deal(app,deck);
            app.dealerCard1.ImageSource = cardStr;
            [cardStr,deck,app.y] = deal(app,deck);
            app.dealerCard2.ImageSource = cardStr;
            app.Deck = deck;
            app.dealerVal = app.x + app.y;
            while (app.dealerVal > 21) && (app.x == 11 || app.y == 11)
                if app.x == 11
                    app.dealerVal = app.dealerVal - 10;
                    app.x = 1;
                elseif app.y == 11
                    app.dealerVal = app.dealerVal - 10;
                    app.y = 1;
                end
            end
        end

        % Button pushed function: hitButton
        function hitPushed(app, event)
            count = app.indexPlayer;
            deck = app.Deck;
            if count == 3
                app.playerCard3.Visible = 'on';
                [cardStr,deck,app.c] = deal(app,deck);
                app.playerCard3.ImageSource = cardStr;
                count = count + 1;
            elseif count == 4
                app.playerCard4.Visible = 'on';
                [cardStr,deck,app.c] = deal(app,deck);
                app.playerCard4.ImageSource = cardStr;
                count = count + 1;
            elseif count == 5
                app.playerCard5.Visible = 'on';
                [cardStr,deck,app.c] = deal(app,deck);
                app.playerCard5.ImageSource = cardStr;
                count = count + 1;
            elseif count == 6
                app.playerCard6.Visible = 'on';
                [cardStr,deck,app.c] = deal(app,deck);
                app.playerCard6.ImageSource = cardStr;
                count = count + 1;
            elseif count == 7
                app.playerCard7.Visible = 'on';
                [cardStr,deck,app.c] = deal(app,deck);
                app.playerCard7.ImageSource = cardStr;
                count = count + 1;
            elseif count == 8
                app.playerCard8.Visible = 'on';
                [cardStr,deck,app.c] = deal(app,deck);
                app.playerCard8.ImageSource = cardStr;
                count = count + 1;
            elseif count == 9
                app.playerCard9.Visible = 'on';
                [cardStr,deck,app.c] = deal(app,deck);
                app.playerCard9.ImageSource = cardStr;
                count = count + 1;
            elseif count == 10
                app.playerCard10.Visible = 'on';
                [cardStr,deck,app.c] = deal(app,deck);
                app.playerCard10.ImageSource = cardStr;
                count = count + 1;
            elseif count == 11
                app.playerCard11.Visible = 'on';
                [cardStr,deck,app.c] = deal(app,deck);
                app.playerCard11.ImageSource = cardStr;
                count = count + 1;
            end
            app.indexPlayer = count;
            app.Deck = deck;
            app.playerVal = app.playerVal + app.c;
            % ACES 1 OR 11
            while (app.playerVal > 21) && (app.a == 11 || app.b == 11 || app.c == 11)
                if app.a == 11
                    app.playerVal = app.playerVal - 10;
                    app.a = 1;
                elseif app.b == 11
                    app.playerVal = app.playerVal - 10;
                    app.b = 1;
                elseif app.c == 11
                    app.playerVal = app.playerVal - 10;
                    app.c = 1;
                end
            end
            app.playerValueText.Value = app.playerVal;
            [pres,dres] = outcome(app,app.playerVal);
            if pres == 2 % bust
                app.money = app.money - app.bet;
                app.moneyText.Value = num2str(app.money);
                app.lose.Visible = 'on';
                app.losses = app.losses + 1;
                pause(2);
                reset(app);
            end
        end

        % Button pushed function: standButton
        function standPushed(app, event)
            app.standButton.Visible = 'off';
            app.hitButton.Visible = 'off';
            app.dealerCardHidden.Visible = 'off';
            app.dealerCard2.Visible = 'on';
            app.dealerValueText.Value = app.dealerVal;
            while app.dealerVal < 17
                count = app.indexDealer;
                deck = app.Deck;
                if count == 3
                    app.dealerCard3.Visible = 'on';
                    [cardStr,deck,app.z] = deal(app,deck);
                    app.dealerCard3.ImageSource = cardStr;
                    count = count + 1;
                elseif count == 4
                    app.dealerCard4.Visible = 'on';
                    [cardStr,deck,app.z] = deal(app,deck);
                    app.dealerCard4.ImageSource = cardStr;
                    count = count + 1;
                elseif count == 5
                    app.dealerCard5.Visible = 'on';
                    [cardStr,deck,app.z] = deal(app,deck);
                    app.dealerCard5.ImageSource = cardStr;
                    count = count + 1;
                elseif count == 6
                    app.dealerCard6.Visible = 'on';
                    [cardStr,deck,app.z] = deal(app,deck);
                    app.dealerCard6.ImageSource = cardStr;
                    count = count + 1;
                elseif count == 7
                    app.dealerCard7.Visible = 'on';
                    [cardStr,deck,app.z] = deal(app,deck);
                    app.dealerCard7.ImageSource = cardStr;
                    count = count + 1;
                elseif count == 8
                    app.dealerCard8.Visible = 'on';
                    [cardStr,deck,app.z] = deal(app,deck);
                    app.dealerCard8.ImageSource = cardStr;
                    count = count + 1;
                elseif count == 9
                    app.dealerCard9.Visible = 'on';
                    [cardStr,deck,app.z] = deal(app,deck);
                    app.dealerCard9.ImageSource = cardStr;
                    count = count + 1;
                elseif count == 10
                    app.dealerCard10.Visible = 'on';
                    [cardStr,deck,app.z] = deal(app,deck);
                    app.dealerCard10.ImageSource = cardStr;
                    count = count + 1;
                end
                app.indexDealer = count;
                app.Deck = deck;
                app.dealerVal = app.dealerVal + app.z;
                % ACES 1 OR 11
                while (app.dealerVal > 21) && (app.x == 11 || app.y == 11 || app.z == 11)
                    if app.x == 11
                        app.dealerVal = app.dealerVal - 10;
                        app.x = 1;
                    elseif app.y == 11
                        app.dealerVal = app.dealerVal - 10;
                        app.y = 1;
                    elseif app.z == 11
                        app.dealerVal = app.dealerVal - 10;
                        app.z = 1;
                    end
                end
                app.dealerValueText.Value = app.dealerVal;
                pause(0.5);
            end
            [pres,dres] = outcome(app,app.playerVal,app.dealerVal);
            if dres == 1 && app.indexDealer == 3 % blackjack
                app.money = app.money - app.bet;
                app.lose.Visible = 'on';
                pause(2);
                app.losses = app.losses + 1;
                reset(app);
            elseif dres == 2 % bust
                app.money = app.money + app.bet;
                app.win.Visible = 'on';
                pause(2);
                app.wins = app.wins + 1;
                reset(app);
            else
                if app.dealerVal == app.playerVal % push
                    app.push.Visible = 'on';
                    pause(2);
                    app.pushes = app.pushes + 1;
                    reset(app);
                elseif app.dealerVal > app.playerVal % dealer wins
                    app.money = app.money - app.bet;
                    app.lose.Visible = 'on';
                    pause(2);
                    app.losses = app.losses + 1;
                    reset(app);
                elseif app.playerVal > app.dealerVal % player wins
                    app.money = app.money + app.bet;
                    app.win.Visible = 'on';
                    pause(2);
                    app.wins = app.wins + 1;
                    reset(app);
                end
            end
            app.moneyText.Value = num2str(app.money);
        end

        % Value changed function: valuesCheckBox
        function showValues(app, event)
            value = app.valuesCheckBox.Value;
            if value == 1
                app.dealerValueText.Visible = 'on';
                app.playerValueText.Visible = 'on';
            else 
                app.dealerValueText.Visible = 'off';
                app.playerValueText.Visible = 'off';
            end
        end

        % Value changed function: statsCheckBox
        function showStats(app, event)
            value = app.statsCheckBox.Value;
            if value == 1
                app.statsGraph.Position = [192,145,300,217];
                data = [app.wins app.losses app.pushes app.blackjacks];
                categorie = categorical({'Wins','Losses','Pushes','Blackjacks'});
                graph = bar(app.statsGraph,categorie,data,'faceColor','flat');
                graph.CData = [0 0 1; 0 1 0; 1 0 0; 0.5 0 0.5];
            else 
                app.statsGraph.Position = [192,145,0,0];
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.backgroundImg = uiimage(app.UIFigure);
            app.backgroundImg.ImageSource = 'blackjackTable.png';
            app.hitButton = uibutton(app.UIFigure, 'push');
            app.hitButton.ButtonPushedFcn = createCallbackFcn(app, @hitPushed, true);
            app.hitButton.Visible = 'off';
            app.hitButton.Text = {'HIT'; ''};
            app.standButton = uibutton(app.UIFigure, 'push');
            app.standButton.ButtonPushedFcn = createCallbackFcn(app, @standPushed, true);
            app.standButton.Visible = 'off';
            app.standButton.Text = 'STAND';
            app.dealButton = uibutton(app.UIFigure, 'push');
            app.dealButton.ButtonPushedFcn = createCallbackFcn(app, @dealPushed, true);
            app.dealButton.Visible = 'off';
            app.dealButton.Text = 'DEAL';
            app.betButton = uibutton(app.UIFigure, 'push');
            app.betButton.ButtonPushedFcn = createCallbackFcn(app, @changeBetPushed, true);
            app.betButton.Text = 'CHANGE BET';
            app.chipsImg = uiimage(app.UIFigure);
            app.chipsImg.Visible = 'off';
            app.chipsImg.ImageSource = 'pokerChips.png';
            app.playerCard1 = uiimage(app.UIFigure);
            app.playerCard1.Visible = 'off';
            app.playerCard1.ImageSource = '2C.png';
            app.playerCard2 = uiimage(app.UIFigure);
            app.playerCard2.Visible = 'off';
            app.playerCard2.ImageSource = '2C.png';
            app.playerCard3 = uiimage(app.UIFigure);
            app.playerCard3.Visible = 'off';
            app.playerCard3.ImageSource = '2C.png';
            app.playerCard4 = uiimage(app.UIFigure);
            app.playerCard4.Visible = 'off';
            app.playerCard4.ImageSource = '2C.png';
            app.playerCard5 = uiimage(app.UIFigure);
            app.playerCard5.Visible = 'off';
            app.playerCard5.ImageSource = '2C.png';
            app.playerCard6 = uiimage(app.UIFigure);
            app.playerCard6.Visible = 'off';
            app.playerCard6.ImageSource = '2C.png';
            app.playerCard7 = uiimage(app.UIFigure);
            app.playerCard7.Visible = 'off';
            app.playerCard7.ImageSource = '2C.png';
            app.playerCard8 = uiimage(app.UIFigure);
            app.playerCard8.Visible = 'off';
            app.playerCard8.ImageSource = '2C.png';
            app.playerCard9 = uiimage(app.UIFigure);
            app.playerCard9.Visible = 'off';
            app.playerCard9.ImageSource = '2C.png';
            app.playerCard10 = uiimage(app.UIFigure);
            app.playerCard10.Visible = 'off';
            app.playerCard10.ImageSource = '2C.png';
            app.playerCard11 = uiimage(app.UIFigure);
            app.playerCard11.Visible = 'off';
            app.playerCard11.ImageSource = '2C.png';
            app.dealerCard1 = uiimage(app.UIFigure);
            app.dealerCard1.Visible = 'off';
            app.dealerCard1.ImageSource = '2C.png';
            app.dealerCard2 = uiimage(app.UIFigure);
            app.dealerCard2.Visible = 'off';
            app.dealerCard2.ImageSource = '2C.png';
            app.dealerCard3 = uiimage(app.UIFigure);
            app.dealerCard3.Visible = 'off';
            app.dealerCard3.ImageSource = '2C.png';
            app.dealerCard4 = uiimage(app.UIFigure);
            app.dealerCard4.Visible = 'off';
            app.dealerCard4.ImageSource = '2C.png';
            app.dealerCard5 = uiimage(app.UIFigure);
            app.dealerCard5.Visible = 'off';
            app.dealerCard5.ImageSource = '2C.png';
            app.dealerCard6 = uiimage(app.UIFigure);
            app.dealerCard6.Visible = 'off';
            app.dealerCard6.ImageSource = '2C.png';
            app.dealerCard7 = uiimage(app.UIFigure);
            app.dealerCard7.Visible = 'off';
            app.dealerCard7.ImageSource = '2C.png';
            app.dealerCard8 = uiimage(app.UIFigure);
            app.dealerCard8.Visible = 'off';
            app.dealerCard8.ImageSource = '2C.png';
            app.dealerCard9 = uiimage(app.UIFigure);
            app.dealerCard9.Visible = 'off';
            app.dealerCard9.ImageSource = '2C.png';
            app.dealerCard10 = uiimage(app.UIFigure);
            app.dealerCard10.Visible = 'off';
            app.dealerCard10.ImageSource = '2C.png';
            app.dealerCardHidden = uiimage(app.UIFigure);
            app.dealerCardHidden.Visible = 'off';
            app.dealerCardHidden.ImageSource = 'red_back.png';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '$';
            app.betText = uitextarea(app.UIFigure);
            app.betText.Editable = 'off';
            app.betText.Value = {'0'};
            app.TOTALLabel = uilabel(app.UIFigure);
            app.TOTALLabel.Text = 'TOTAL $';
            app.moneyText = uitextarea(app.UIFigure);
            app.moneyText.Editable = 'off';
            app.moneyText.Value = {'100'};
            app.EnternewbetamountEditFieldLabel = uilabel(app.UIFigure);
            app.EnternewbetamountEditFieldLabel.Visible = 'off';
            app.EnternewbetamountEditFieldLabel.Text = 'Enter new bet amount:';
            app.changeBetField = uieditfield(app.UIFigure, 'numeric');
            app.changeBetField.RoundFractionalValues = 'on';
            app.changeBetField.ValueChangedFcn = createCallbackFcn(app, @betChanged, true);
            app.changeBetField.Visible = 'off';
            app.playerBlackjack = uilabel(app.UIFigure);
            app.playerBlackjack.Visible = 'off';
            app.playerBlackjack.Text = 'BLACKJACK!';
            app.win = uilabel(app.UIFigure);
            app.win.Visible = 'off';
            app.win.Text = 'YOU WIN!';
            app.lose = uilabel(app.UIFigure);
            app.lose.Visible = 'off';
            app.lose.Text = 'YOU LOSE';
            app.push = uilabel(app.UIFigure);
            app.push.Visible = 'off';
            app.push.Text = 'PUSH';
            app.valuesCheckBox = uicheckbox(app.UIFigure);
            app.valuesCheckBox.ValueChangedFcn = createCallbackFcn(app, @showValues, true);
            app.valuesCheckBox.Text = {'Show Values'; ''};
            app.dealerValueText = uieditfield(app.UIFigure, 'numeric');
            app.dealerValueText.Editable = 'off';
            app.dealerValueText.Visible = 'off';
            app.playerValueText = uieditfield(app.UIFigure, 'numeric');
            app.playerValueText.Editable = 'off';
            app.playerValueText.Visible = 'off';
            app.gameOverMsg = uitextarea(app.UIFigure);
            app.gameOverMsg.Visible = 'off';
            app.gameOverMsg.Value = {''; ''; 'You ran out of money.'; 'GAME OVER'};
            app.overBet = uilabel(app.UIFigure);
            app.overBet.Visible = 'off';
            app.overBet.Text = {'You don''t have that '; 'much money.'};
            app.statsCheckBox = uicheckbox(app.UIFigure);
            app.statsCheckBox.ValueChangedFcn = createCallbackFcn(app, @showStats, true);
            app.statsCheckBox.Text = 'Stats';
            app.statsGraph = uiaxes(app.UIFigure);
            title(app.statsGraph, 'Statistics')
            xlabel(app.statsGraph, '')
            ylabel(app.statsGraph, '')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = blackjack

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end